classdef driftcorrectGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIDriftCorrect             matlab.ui.Figure
        OptionsPanel               matlab.ui.container.Panel
        ShiftsButton               matlab.ui.control.Button
        NrSpaceEditField           matlab.ui.control.NumericEditField
        NrspacebinsEditFieldLabel  matlab.ui.control.Label
        NrTimeEditField            matlab.ui.control.NumericEditField
        NrtimebinsLabel            matlab.ui.control.Label
        YaxisDropDown              matlab.ui.control.DropDown
        YaxisDropDownLabel         matlab.ui.control.Label
        OutputfilePanel            matlab.ui.container.Panel
        CopyZCheckBox              matlab.ui.control.CheckBox
        SaveButton                 matlab.ui.control.Button
        InitialsField              matlab.ui.control.EditField
        InitialsEditFieldLabel     matlab.ui.control.Label
        InputfilePanel             matlab.ui.container.Panel
        FindIDButton               matlab.ui.control.Button
        ProbeIDField               matlab.ui.control.NumericEditField
        ProbeIDEditFieldLabel      matlab.ui.control.Label
        LoadIDButton               matlab.ui.control.Button
        FilenameField              matlab.ui.control.EditField
        FileLabel                  matlab.ui.control.Label
        ShiftAxes                  matlab.ui.control.UIAxes
        BinAxes                    matlab.ui.control.UIAxes
        AfterAxes                  matlab.ui.control.UIAxes
        BeforeAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        filenameID % filename of id file
        probeID %probe read
        spkInfo %spike data
        binData %bin counts
        sampleVec %sample bin edges
        spaceVec %space bin edges
        binShifts %vector with shifts per bin
        binShiftR %vector with significance of crosscorr
    end

    methods (Access = private)

        function loadSpkInfo(app,NJobs)
            %loops through all of the job files to generate spkInfo

            %get filename parts
            [fpath,fname,~]=fileparts(app.filenameID);
            fpart=strsplit(fname,'_');
            expname=[fpart{1} '_' fpart{2} '_' fpart{3}];

            wb=uiprogressdlg(app.UIDriftCorrect,'Title','Please wait','Message','Generating spkInfo');

            %construct spkInfo
            for i=0:NJobs-1
                wb.Value=i/(NJobs-1);

                fname=fullfile(fpath,'SpikeFiles',[expname '_j' num2str(i) '_p' num2str(app.probeID) '_spkinfo']);

                load(fname); %generates spk

                %we only need a few fields
                if i==0
                    sInfo.spkTimesDet=spk.spkTimesDet;
                    sInfo.comZEnShaftDet=spk.comZEnShaftDet;
                    sInfo.comZMinShaftDet=spk.comZMinShaftDet;
                    sInfo.flagDuplicate=spk.flagDuplicate;
                else
                    sInfo.spkTimesDet=[sInfo.spkTimesDet spk.spkTimesDet];
                    sInfo.comZEnShaftDet=[sInfo.comZEnShaftDet spk.comZEnShaftDet];
                    sInfo.comZMinShaftDet=[sInfo.comZMinShaftDet spk.comZMinShaftDet];
                    sInfo.flagDuplicate=[sInfo.flagDuplicate spk.flagDuplicate];
                end

                clear spk;
            end

            close(wb);

            %remove duplicates
            app.spkInfo.spkTimesDet=sInfo.spkTimesDet(sInfo.flagDuplicate==0);
            app.spkInfo.comZEnShaftDet=sInfo.comZEnShaftDet(sInfo.flagDuplicate==0);
            app.spkInfo.comZMinShaftDet=sInfo.comZMinShaftDet(sInfo.flagDuplicate==0);

        end


        function plotBefore(app)
            yprop=app.YaxisDropDown.Value;
            h=plot(app.BeforeAxes,app.spkInfo.spkTimesDet,app.spkInfo.(yprop),'o','MarkerSize',1);
            set(h,'MarkerFaceColor','b','MarkerEdgeColor','b');
            app.BeforeAxes.YLabel.String=yprop;
        end

        function computeBinData(app)
            %create bin vector for time
            minSample=0;
            maxSample=max(app.spkInfo.spkTimesDet);
            NrSampleBin=app.NrTimeEditField.Value;
            app.sampleVec=linspace(minSample,maxSample,NrSampleBin);


            %create bin vector for space
            yprop=app.YaxisDropDown.Value;
            minSpace=min(app.spkInfo.(yprop));
            maxSpace=max(app.spkInfo.(yprop));
            NrSpaceBin=app.NrSpaceEditField.Value;
            app.spaceVec=linspace(minSpace,maxSpace,NrSpaceBin);

            %step through bins and average across time within them
            app.binData=[];
            for i=1:length(app.sampleVec)-1
                tf=app.spkInfo.spkTimesDet>=app.sampleVec(i) & app.spkInfo.spkTimesDet<=app.sampleVec(i+1);
                N=histcounts(app.spkInfo.(yprop)(tf),app.spaceVec);

                app.binData(i,:)=N;
            end

            %compute shifts between bins
            app.binShifts=[];
            app.binShiftR=[];
            for i=1:size(app.binData,1)-1
                [r,lags]=xcorr(app.binData(i,:),app.binData(i+1,:),'normalized');
                app.binShifts(i)=lags(r==max(r));
                app.binShiftR(i)=max(r);
            end
        end

        function plotBinData(app)

            for i=1:size(app.binData,1)
                xdata=app.binData(i,:)/max(app.binData(i,:))+(i-1);
                plot(app.BinAxes,xdata,app.spaceVec(1:end-1));
                hold(app.BinAxes,'on')
            end
            hold(app.BinAxes,'off')
        end

        %now compute correlations
        function plotBinShifts(app)
            yyaxis(app.ShiftAxes,'left')
            plot(app.ShiftAxes,app.binShifts)
            ylabel(app.ShiftAxes,'Shift')
            yyaxis(app.ShiftAxes,'right')
            plot(app.ShiftAxes,app.binShiftR)
            ylabel(app.ShiftAxes,'Corr') 
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadIDButton
        function LoadIDButtonPushed(app, event)
            if ~isempty(app.FilenameField.Value)
                %get filename
                app.filenameID=app.FilenameField.Value;
                app.probeID=app.ProbeIDField.Value;

                %load data - we are loading all jobs here
                load(app.filenameID);
                NJobs=length(id.extractSpikes.jobStart{app.probeID});
                app.loadSpkInfo(NJobs);

                %plot before
                app.plotBefore();

                %compute average
                app.computeBinData();

                %plot average
                app.plotBinData();

                %plot bin shifts
                app.plotBinShifts();

            end
        end

        % Button pushed function: FindIDButton
        function FindIDButtonPushed(app, event)
            [f,p] = uigetfile('*.mat','Select ID file');
            if ~isequal(p,0)
                app.FilenameField.Value=fullfile(p,f);
            end
            figure(app.UIDriftCorrect);
        end

        % Value changed function: YaxisDropDown
        function YaxisDropDownValueChanged(app, event)

            %plot before
            app.plotBefore();

            %compute average
            app.computeBinData();

            %plot average
            app.plotBinData();

            %plot bin shifts
            app.plotBinShifts();
        end

        % Value changed function: NrTimeEditField
        function NrTimeEditFieldValueChanged(app, event)
            %compute average
            app.computeBinData();

            %plot average
            app.plotBinData();

            %plot bin shifts
            app.plotBinShifts();

        end

        % Value changed function: NrSpaceEditField
        function NrSpaceEditFieldValueChanged(app, event)
            %compute average
            app.computeBinData();

            %plot average
            app.plotBinData();

            %plot bin shifts
            app.plotBinShifts();
        end

        % Button pushed function: ShiftsButton
        function ShiftsButtonPushed(app, event)
            %compute average to be sure it's up to date
            app.computeBinData();


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIDriftCorrect = uifigure('Visible', 'off');
            app.UIDriftCorrect.Name = 'MATLAB App';
            app.BeforeAxes = uiaxes(app.UIDriftCorrect);
            title(app.BeforeAxes, 'Before')
            xlabel(app.BeforeAxes, 'spkTimesDet')
            ylabel(app.BeforeAxes, 'Y')
            zlabel(app.BeforeAxes, 'Z')
            app.AfterAxes = uiaxes(app.UIDriftCorrect);
            title(app.AfterAxes, 'After')
            xlabel(app.AfterAxes, 'spkTimesDet')
            ylabel(app.AfterAxes, 'Y')
            zlabel(app.AfterAxes, 'Z')
            app.BinAxes = uiaxes(app.UIDriftCorrect);
            title(app.BinAxes, 'Binned')
            xlabel(app.BinAxes, 'Bin')
            zlabel(app.BinAxes, 'Z')
            app.ShiftAxes = uiaxes(app.UIDriftCorrect);
            xlabel(app.ShiftAxes, 'Bins')
            zlabel(app.ShiftAxes, 'Z')
            app.InputfilePanel = uipanel(app.UIDriftCorrect);
            app.InputfilePanel.Title = 'Input file';
            app.FileLabel = uilabel(app.InputfilePanel);
            app.FileLabel.Text = 'File';
            app.FilenameField = uieditfield(app.InputfilePanel, 'text');
            app.LoadIDButton = uibutton(app.InputfilePanel, 'push');
            app.LoadIDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIDButtonPushed, true);
            app.LoadIDButton.Text = 'Load ID file';
            app.ProbeIDEditFieldLabel = uilabel(app.InputfilePanel);
            app.ProbeIDEditFieldLabel.Text = 'Probe ID';
            app.ProbeIDField = uieditfield(app.InputfilePanel, 'numeric');
            app.ProbeIDField.Value = 1;
            app.FindIDButton = uibutton(app.InputfilePanel, 'push');
            app.FindIDButton.ButtonPushedFcn = createCallbackFcn(app, @FindIDButtonPushed, true);
            app.FindIDButton.Text = 'Find';
            app.OutputfilePanel = uipanel(app.UIDriftCorrect);
            app.OutputfilePanel.Title = 'Output file';
            app.InitialsEditFieldLabel = uilabel(app.OutputfilePanel);
            app.InitialsEditFieldLabel.Text = 'Initials';
            app.InitialsField = uieditfield(app.OutputfilePanel, 'text');
            app.SaveButton = uibutton(app.OutputfilePanel, 'push');
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save file';
            app.CopyZCheckBox = uicheckbox(app.OutputfilePanel);
            app.CopyZCheckBox.Text = 'Copy to Z';
            app.OptionsPanel = uipanel(app.UIDriftCorrect);
            app.OptionsPanel.Title = 'Options';
            app.YaxisDropDownLabel = uilabel(app.OptionsPanel);
            app.YaxisDropDownLabel.Text = 'Y axis';
            app.YaxisDropDown = uidropdown(app.OptionsPanel);
            app.YaxisDropDown.Items = {'comZEnShaftDet', 'comZMinShaftDet'};
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownValueChanged, true);
            app.YaxisDropDown.Value = 'comZEnShaftDet';
            app.NrtimebinsLabel = uilabel(app.OptionsPanel);
            app.NrtimebinsLabel.Text = 'Nr time bins';
            app.NrTimeEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.NrTimeEditField.Limits = [1 100];
            app.NrTimeEditField.ValueChangedFcn = createCallbackFcn(app, @NrTimeEditFieldValueChanged, true);
            app.NrTimeEditField.Value = 10;
            app.NrspacebinsEditFieldLabel = uilabel(app.OptionsPanel);
            app.NrspacebinsEditFieldLabel.Text = 'Nr space bins';
            app.NrSpaceEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.NrSpaceEditField.Limits = [1 1000];
            app.NrSpaceEditField.ValueChangedFcn = createCallbackFcn(app, @NrSpaceEditFieldValueChanged, true);
            app.NrSpaceEditField.Value = 100;
            app.ShiftsButton = uibutton(app.OptionsPanel, 'push');
            app.ShiftsButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftsButtonPushed, true);
            app.ShiftsButton.Text = 'Apply shifts';
            % Show the figure after all components are created
            app.UIDriftCorrect.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = driftcorrectGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIDriftCorrect)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIDriftCorrect)
        end
    end
end