classdef setIDfile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UISetID              matlab.ui.Figure
        ProbeIDSpinner       matlab.ui.control.Spinner
        ProbeIDSpinnerLabel  matlab.ui.control.Label
        JIDStopSpinner       matlab.ui.control.Spinner
        JobIDStopLabel       matlab.ui.control.Label
        JIDStartSpinner      matlab.ui.control.Spinner
        JobIDStartLabel      matlab.ui.control.Label
        FindIDfileButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainGui % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainGui=mainapp;
        end

        % Button pushed function: FindIDfileButton
        function FindIDfileButtonPushed(app, event)
            [f,p] = uigetfile('*.mat','Select ID file');
            if ~isequal(p,0)
                jobIDstart=app.JIDStartSpinner.Value;
                jobIDstop=app.JIDStopSpinner.Value;
                probe=app.ProbeIDSpinner.Value;
                
                getIDfile(app.mainGui,jobIDstart,jobIDstop,probe,p,f);
                delete(app);
            else %cancel
                getIDfile(app.mainGui,0,0,0,0,0);
                delete(app);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UISetID = uifigure('Visible', 'off');
            app.UISetID.Name = 'MATLAB App';
            app.FindIDfileButton = uibutton(app.UISetID, 'push');
            app.FindIDfileButton.ButtonPushedFcn = createCallbackFcn(app, @FindIDfileButtonPushed, true);
            app.FindIDfileButton.Text = 'Find ID file';
            app.JobIDStartLabel = uilabel(app.UISetID);
            app.JobIDStartLabel.Text = 'Job ID Start';
            app.JIDStartSpinner = uispinner(app.UISetID);
            app.JobIDStopLabel = uilabel(app.UISetID);
            app.JobIDStopLabel.Text = 'Job ID Stop';
            app.JIDStopSpinner = uispinner(app.UISetID);
            app.JIDStopSpinner.Value = 199;
            app.ProbeIDSpinnerLabel = uilabel(app.UISetID);
            app.ProbeIDSpinnerLabel.Text = 'Probe ID';
            app.ProbeIDSpinner = uispinner(app.UISetID);
            app.ProbeIDSpinner.Value = 1;
            % Show the figure after all components are created
            app.UISetID.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setIDfile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UISetID)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UISetID)
        end
    end
end