classdef sortGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UISortGui               matlab.ui.Figure
        MainAxes                matlab.ui.control.UIAxes
        FilePanel               matlab.ui.container.Panel
        LoadButton              matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        InitialsEditFieldLabel  matlab.ui.control.Label
        InitialsField           matlab.ui.control.EditField
        JobIDStartLabel         matlab.ui.control.Label
        JIDStartSpinner         matlab.ui.control.Spinner
        StopSpinnerLabel        matlab.ui.control.Label
        JIDStopSpinner          matlab.ui.control.Spinner
        ProbeIDSpinnerLabel     matlab.ui.control.Label
        ProbeIDSpinner          matlab.ui.control.Spinner
        IDFileLabel             matlab.ui.control.Label
        FilenameField           matlab.ui.control.EditField
        UnitsPanel              matlab.ui.container.Panel
        AddUnitButton           matlab.ui.control.Button
        UnitTable               matlab.ui.control.Table
        EditunitPanel           matlab.ui.container.Panel
        AddPROIButton           matlab.ui.control.Button
        AddMROIButton           matlab.ui.control.Button
        VisibleCheckBox         matlab.ui.control.CheckBox
        UnitSpinnerLabel        matlab.ui.control.Label
        UnitSpinner             matlab.ui.control.Spinner
        DeleteUnitButton        matlab.ui.control.Button
        CategoryLabel           matlab.ui.control.Label
        CatDropDown             matlab.ui.control.DropDown
        PlotPanel               matlab.ui.container.Panel
        MarkersizeSpinnerLabel  matlab.ui.control.Label
        MarkerSzSpinner         matlab.ui.control.Spinner
        ModeTabGroup            matlab.ui.container.TabGroup
        ProbemodeTab            matlab.ui.container.Tab
        XaxisDropDownLabel      matlab.ui.control.Label
        XaxisDropDown           matlab.ui.control.DropDown
        YaxisDropDownLabel      matlab.ui.control.Label
        YaxisDropDown           matlab.ui.control.DropDown
        LimitChButton           matlab.ui.control.Button
        ResetChButton           matlab.ui.control.Button
        SelChLabel              matlab.ui.control.Label
        TetrodemodeTab          matlab.ui.container.Tab
        DetchannelSpinnerLabel  matlab.ui.control.Label
        TetDetChSpinner         matlab.ui.control.Spinner
        XaxisDropDown_2Label    matlab.ui.control.Label
        TetXaxisDropDown        matlab.ui.control.DropDown
        YaxisDropDown_2Label    matlab.ui.control.Label
        TetYaxisDropDown        matlab.ui.control.DropDown
        ChXLabel                matlab.ui.control.Label
        TetChXDropDown          matlab.ui.control.DropDown
        ChYLabel                matlab.ui.control.Label
        TetChYDropDown          matlab.ui.control.DropDown
        UnitwaveformsPanel      matlab.ui.container.Panel
        UnitSpinner_2Label      matlab.ui.control.Label
        UnitWfSpinner           matlab.ui.control.Spinner
        WfAxes                  matlab.ui.control.UIAxes
        RefreshWfButton         matlab.ui.control.Button
        NrSpkSpinnerLabel       matlab.ui.control.Label
        NrSpkSpinner            matlab.ui.control.Spinner
        UnitfootprintPanel      matlab.ui.container.Panel
        UnitSpinner_2Label_2    matlab.ui.control.Label
        UnitFpSpinner           matlab.ui.control.Spinner
        FpAxes                  matlab.ui.control.UIAxes
        PreprocessingPanel      matlab.ui.container.Panel
        RejArtButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        spkInfo % properties for each spike
        spkProp % properties for probe mode
        spkPropTet % properties for tetrode mode
        probes %probes info
        xProp %property for x axis
        yProp %property for y axis
        xPropTet %x axis tetrode
        yPropTet %y axis tetrode
        detChTet %detection channel tetrode
        xChTet %x channel
        yChTet %y channel
        selectedCh %subset of channels to work on - these are relative to the probe (e.g., 1-64 even for second probe)
        units %all info related to units
        unitInfo %info table for units
        nrUnits %nr units for each probe
        sampleFreq %sampling frequency
        jobEdges %start and stop of samples in jobs
        filepath %path of spkfile
        filename %filename of spkfile
        artThreshold % threshold for artefacts
        artNCh %threshold for artefacts ((in number of channels)
    end
    
    methods (Access = private)
        
        function loadSpkInfo(app)
            %replaces mergeSpikeProps - loops through all of the job files
            %to generate spkInfo
            
            %get filename parts
            fpart=strsplit(app.filename,'_');
            expname=[fpart{1} '_' fpart{2} '_' fpart{3}];
            
            wb=uiprogressdlg(app.UISortGui,'Title','Please wait','Message','Generating spkInfo');
            
            %get job range and probes
            jobIDstart=app.JIDStartSpinner.Value;
            jobIDstop=app.JIDStopSpinner.Value;
            probeId=app.ProbeIDSpinner.Value;
            
            %construct spkInfo
            for i=jobIDstart:jobIDstop
                wb.Value=(i-jobIDstart)/(jobIDstop-jobIDstart);
                
                fname=fullfile(app.filepath,'SpikeFiles',[expname '_j' num2str(i) '_p' num2str(probeId) '_spkinfo']);
                
                load(fname); %generates spk
                dataVar=fieldnames(spk);
                
                for n=1:length(dataVar)
                    if i==jobIDstart
                        app.spkInfo.(dataVar{n})=spk.(dataVar{n});
                    else
                        if ~strcmp(dataVar{n},'expname') & ~strcmp(dataVar{n},'probeId')
                            app.spkInfo.(dataVar{n})=[app.spkInfo.(dataVar{n}) spk.(dataVar{n})];
                        end
                    end
                    
                end
                
                clear spk;
            end
            
            close(wb);
            
        end
        
        function updateMainPlot(app)
            %this is just for plotting; unit assignments are handled for
            %the full data set in a separate function (so ok to reduce the
            %data set here)
            
            
            %remove any artefacts (labeled as -1)
            tf=app.spkInfo.unitid~=-1;
            
            %also remove all duplicates
            tf = tf & app.spkInfo.flagDuplicate~=1;
            
            %reduce to selected channels or to correct probe only
            if ~isempty(app.selectedCh) %all data from one probe
                tf = tf & ismember(app.spkInfo.detCh,app.selectedCh);
            end
            
            %remove units if selected
            if ~isempty(app.unitInfo)
                visUnit=app.unitInfo.Vis; %returns vector with entry per unit
                visIdx=find(visUnit==0); %might result in vector
                tf = tf& ~ismember(app.spkInfo.unitid,visIdx);
            end
            
            %get data
            xData=app.spkInfo.(app.xProp)(tf);
            yData=app.spkInfo.(app.yProp)(tf);
            unitData=app.spkInfo.unitid(tf);
            
            
            %plot
            c=colororder;
            neuronId=unique(unitData);
            ms=app.MarkerSzSpinner.Value;
            for i=neuronId
                if i==0
                    cidx=c(1,:);
                else
                    cidx=app.units(i).color;
                end
                
                h=plot(app.MainAxes,xData(unitData==i),yData(unitData==i),'o','MarkerSize',ms);
                set(h,'MarkerFaceColor',cidx,'MarkerEdgeColor',cidx);
                hold(app.MainAxes,'on')
            end
            hold(app.MainAxes,'off')
            
            %label axes
            app.MainAxes.XLabel.String=app.xProp;
            app.MainAxes.YLabel.String=app.yProp;
        end
        
        function updateMainPlotTet(app)
            %this handles the plotting for the tetrode mode
            
            %we only need the data for events that are detected on the
            %detection channels
            tf=(app.spkInfo.detCh==app.detChTet);
            
            %implement artefact selection
            tf=tf & app.spkInfo.unitid~=-1;
            
            %also remove all duplicates
            tf = tf & app.spkInfo.flagDuplicate~=1;
            
            xData=app.spkInfo.(app.xPropTet)(tf);
            yData=app.spkInfo.(app.yPropTet)(tf);
            unitData=app.spkInfo.unitid(tf);
            
            %turn into regular array and reshape
            nCh=size(xData{1},2);
            xData=cell2mat(xData);
            yData=cell2mat(yData);
            xData=reshape(xData,nCh,sum(tf));
            yData=reshape(yData,nCh,sum(tf));
            
            %now get the data for the channels selected for x and y
            chList=app.spkInfo.chListAll(tf);
            chList=chList{1};
            
            xidx=find(chList==app.xChTet);
            yidx=find(chList==app.yChTet);
            xData=xData(xidx,:);
            yData=yData(yidx,:);
            
            %remove units if selected
            if ~isempty(app.unitInfo)
                visUnit=app.unitInfo.Vis; %returns vector with entry per unit
                visIdx=find(visUnit==0); %might result in vector
                tf=~ismember(unitData,visIdx);
                xData=xData(tf);
                yData=yData(tf);
                unitData=unitData(tf);
            end
            
            %plot
            c=colororder;
            neuronId=unique(unitData);
            ms=app.MarkerSzSpinner.Value;
            for i=neuronId
                if i==0
                    cidx=c(1,:);
                else
                    cidx=app.units(i).color;
                end
                
                h=plot(app.MainAxes,xData(unitData==i),yData(unitData==i),'o','MarkerSize',ms);
                set(h,'MarkerFaceColor',cidx,'MarkerEdgeColor',cidx);
                hold(app.MainAxes,'on')
            end
            hold(app.MainAxes,'off')
            
            %label axes
            app.MainAxes.XLabel.String=app.xPropTet;
            app.MainAxes.YLabel.String=app.yPropTet;
            
        end
        
        function assignUnit(app,unitid,roihandle,actionflag)
            %edit unitid vector
            %actionflag:
            % 1: add a positive roi
            % -1: add a negative roi
            % this operates on the data as shown (i.e., 
            % artefacts are not included)
            
            %remove any artefacts (labeled as -1)
            tf=app.spkInfo.unitid~=-1;
            
            %also remove all duplicates
            tf = tf & app.spkInfo.flagDuplicate~=1;
            
            %reduce to selected channels or to correct probe only
            if ~isempty(app.selectedCh) %all data from one probe
                tf = tf & ismember(app.spkInfo.detCh,app.selectedCh);
            end
            
            
            %get data
            xData=app.spkInfo.(app.xProp);
            yData=app.spkInfo.(app.yProp);
            idxData=[1:length(xData)];
            
            xData=xData(tf);
            yData=yData(tf);
            idxData=idxData(tf);
           
            
            %get data in ROI
            selUnit=inROI(roihandle,xData,yData);
            
            %edit unitid: add unit for all but negative rois
            if actionflag~=-1
                app.spkInfo.unitid(idxData(selUnit))=unitid;
            else
                app.spkInfo.unitid(idxData(selUnit))=0;
            end
            
        end
        
        function assignUnitTet(app,unitid,roihandle,actionflag)
            %edit unitid vector - tetrode mode
            %actionflag:
            % 1: add a positive roi
            % -1: add a negative roi
            
            %get data
            tf= app.spkInfo.detCh==app.detChTet;
            
            %implement artefact selection
            tf=tf & app.spkInfo.unitid~=-1;
            
            %also remove all duplicates
            tf = tf & app.spkInfo.flagDuplicate~=1;
            
            xData=app.spkInfo.(app.xPropTet);
            yData=app.spkInfo.(app.yPropTet);
            idxData=[1:length(xData)];
            
            xData=xData(tf);
            yData=yData(tf);
            idxData=idxData(tf);
            
            %turn into regular array and reshape
            nCh=size(xData{1},2);
            xData=cell2mat(xData);
            yData=cell2mat(yData);
            xData=reshape(xData,nCh,sum(tf));
            yData=reshape(yData,nCh,sum(tf));
            
            %now get the data for the channels selected for x and y
            chList=app.spkInfo.chListAll(tf);
            chList=chList{1};
            xidx=find(chList==app.xChTet);
            yidx=find(chList==app.yChTet);
            
            xData=xData(xidx,:);
            yData=yData(yidx,:);
            
            %get data in ROI
            selUnit=inROI(roihandle,xData,yData);
            
            %edit unitid: add unit for all but negative rois
            if actionflag~=-1
                app.spkInfo.unitid(idxData(selUnit))=unitid;
            else
                app.spkInfo.unitid(idxData(selUnit))=0;
            end
            
        end
        
        
        function removeUnit(app,remUnit)
            %delete and renumber units
            
            %remove the deleted unit from unitid
            app.spkInfo.unitid(app.spkInfo.unitid==remUnit)=0;
            
            %renumber the remainder
            for i=remUnit+1:app.nrUnits
                app.spkInfo.unitid(app.spkInfo.unitid==i)=i-1;
            end
        end
        
        function updateUnitTable(app)
            %update table with unit info
            app.UnitTable.Data=app.unitInfo;
            
            %set colors for units correctly
            for i=1:app.nrUnits
                cidx=app.units(i).color;
                s=uistyle('BackgroundColor',cidx);
                addStyle(app.UnitTable,s,'cell',[i,1])
            end
        end
        
        
        function computeISI(app,unitIsi)
            %minimum isi for now set to 1.2ms
            minISI=0.0012;
            minISIsample=round(minISI*app.sampleFreq);
            
            %compute minimum ISI violations for a particular unit
            ts=sort(app.spkInfo.spkTimesDet(app.spkInfo.unitid==unitIsi));
            diffTs=diff(ts); %difference between spikes in samples
            
            
            perISIv=sum(diffTs<minISIsample)/length(ts);
            app.unitInfo.ISIv(unitIsi)=perISIv;
            
        end


        function updateWfPlot(app,nrunit)
            %plot waveforms for unit
            %determine how many should be plotted
            nrSpk=app.NrSpkSpinner.Value;
            
            %get timestamps and detection channel for unit
            ts=app.spkInfo.spkTimesDet(app.spkInfo.unitid==nrunit);
            chidx=app.spkInfo.detChRaw(app.spkInfo.unitid==nrunit); %in the spike file both probes are mixed
            
            %randomly subselect if there are more than nrSpk
            if length(ts)>nrSpk
                ridx=randperm(length(ts),nrSpk);
                ts=ts(ridx);
                chidx=chidx(ridx);
            end
            
            %now read waveforms - map timestamps onto job files
            tsbin=discretize(ts,app.jobEdges); %this works even if not all jobs were merged for spike file generation
            tsunique=unique(tsbin);
            
            %need loop to open files and read waveforms - first loop over
            %job files
            count=1;
            for i=1:length(tsunique)
                load(fullfile(app.filepath,'SpikeFiles',[app.spkInfo.expname '_j' num2str(tsunique(i)-1) '_spike'])); %generates spikeData
                
                %within job files loop over waveforms
                tsidx=find(tsbin==tsunique(i));
                for j=1:length(tsidx)
                    widx=find(spikeData(chidx(tsidx(j))).spikeTimes==ts(tsidx(j))); %find which waveform
                    
                    wv=spikeData(chidx(tsidx(j))).Wvfrms(widx,:,1); %get waveform
                    waveformData(count,:)=squeeze(wv);
                    
                    count=count+1;
                end
            end
            
            %now plot
            plot(app.WfAxes,waveformData')
            
        end
        
        function updateFpPlot(app,nrunit)
            %figure out footprint of a unit
            pr=app.probes;
            
            %get channels
            chidx=app.spkInfo.detCh(app.spkInfo.unitid==nrunit);
            
            %figure out proportion for each channel
            %accumarray sums up number of entries for each bin
            chdist=accumarray(chidx',1,[pr.nChannels 1]);
            chdist=chdist/length(chidx)*100;
            
            %plot
            tf=(chdist==0);
            scatter(app.FpAxes,pr.x(tf),pr.z(tf),2,'k','filled')
            hold(app.FpAxes,'on')
            scatter(app.FpAxes,pr.x(~tf),pr.z(~tf),[],chdist(~tf),'filled')
            hold(app.FpAxes,'off')
            axis(app.FpAxes,[min(pr.x)-50 max(pr.x)+50 min(pr.z)-50 max(pr.z)+50]);
            app.FpAxes.XLabel.String='X';
            app.FpAxes.YLabel.String='Z';
        end
        
        function setButtonsUnits(app,nrUnits,selUnit)
            %function to handle enabling/disabling of buttons based on nr
            %units
            
            %no units - disable all of the unit related things
            if nrUnits==0
                app.DeleteUnitButton.Enable=0;
                app.UnitSpinner.Enable=0;
                app.UnitWfSpinner.Enable=0;
                app.UnitFpSpinner.Enable=0;
                app.AddMROIButton.Enable=0;
                app.AddPROIButton.Enable=0;
                app.CatDropDown.Enable=0;
                app.VisibleCheckBox.Enable=0;
                app.NrSpkSpinner.Enable=0;
                app.RefreshWfButton.Enable=0;
            elseif nrUnits==1
                app.DeleteUnitButton.Enable=1;
                app.UnitSpinner.Value=1;
                app.UnitSpinner.Enable=0;
                app.UnitWfSpinner.Value=1;
                app.UnitWfSpinner.Enable=0;
                app.UnitFpSpinner.Value=1;
                app.UnitFpSpinner.Enable=0;
                app.AddMROIButton.Enable=1;
                app.AddPROIButton.Enable=1;
                app.CatDropDown.Enable=1;
                app.VisibleCheckBox.Enable=1;
                app.NrSpkSpinner.Enable=1;
                app.RefreshWfButton.Enable=1;
            else
                app.DeleteUnitButton.Enable=1;
                app.UnitSpinner.Enable=1;
                app.UnitSpinner.Limits=[1 nrUnits];
                app.UnitSpinner.Value=selUnit;
                
                app.UnitWfSpinner.Enable=1;
                app.UnitWfSpinner.Limits=[1 nrUnits];
                app.UnitWfSpinner.Value=selUnit;
                
                app.UnitFpSpinner.Enable=1;
                app.UnitFpSpinner.Limits=[1 nrUnits];
                app.UnitFpSpinner.Value=selUnit;
                app.AddMROIButton.Enable=1;
                app.AddPROIButton.Enable=1;
                app.CatDropDown.Enable=1;
                app.VisibleCheckBox.Enable=1;
                app.NrSpkSpinner.Enable=1;
                app.RefreshWfButton.Enable=1;
            end
            
        end


        function setButtons(app,buttonState)
            app.MarkerSzSpinner.Enable=buttonState;
            app.XaxisDropDown.Enable=buttonState;
            app.YaxisDropDown.Enable=buttonState;
            app.LimitChButton.Enable=buttonState;
            app.ResetChButton.Enable=buttonState;
            app.TetDetChSpinner.Enable=buttonState;
            app.TetXaxisDropDown.Enable=buttonState;
            app.TetYaxisDropDown.Enable=buttonState;
            app.TetChXDropDown.Enable=buttonState;
            app.TetChYDropDown.Enable=buttonState;
            app.AddUnitButton.Enable=buttonState;
            app.RejArtButton.Enable=buttonState;
        end
        
        
    end
    
    methods (Access = public)
        
        function getSelCh(app,selCh)
            app.selectedCh=selCh;
        end
        
        function getArtefacts(app,NCh,threshold)
            app.artNCh=NCh;
            app.artThreshold=threshold;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [f,p] = uigetfile('*.mat','Select ID file');
            if ~isequal(p,0)
                
                app.FilenameField.Value=f;
                app.filepath=p;
                app.filename=f;
                
                %load id data
                load(fullfile(p,f)); %generates spkInfo - still faster than matfile
                
                %construct spkInfo
                app.loadSpkInfo();
                
                %get general info
                app.probes=id.probes(app.spkInfo.probeId);
                app.sampleFreq=id.sampleFreq;
                app.jobEdges=id.extractSpikes.jobEdges;
                
                
                %initialize channel selection
                app.selectedCh=[];
                
                %get property names - for plotting purposes, only retain the ones
                %without the all
                sp=fieldnames(app.spkInfo);
                app.spkProp=sp(~contains(sp,'All'));
                
                %need to remove dateprops and expname
                app.spkProp=app.spkProp(~strcmp(app.spkProp,'jobRange'));
                app.spkProp=app.spkProp(~strcmp(app.spkProp,'expname'));
                app.spkProp=app.spkProp(~strcmp(app.spkProp,'flagDuplicate'));
                app.spkProp=app.spkProp(~strcmp(app.spkProp,'probeId'));
                app.spkProp=app.spkProp(~strcmp(app.spkProp,'detChRaw'));
                
                %set dropdowns
                app.XaxisDropDown.Items=app.spkProp;
                app.YaxisDropDown.Items=app.spkProp;
                
                app.XaxisDropDown.Value='detCh'; %this has to be there for other stuff, so no check
                app.xProp=app.XaxisDropDown.Value;
                
                if any(strcmp(app.spkProp,'AmpMinDet'))
                    app.YaxisDropDown.Value='AmpMinDet';
                else
                    app.YaxisDropDown.Value=app.spkProp{2};
                end
                app.yProp=app.YaxisDropDown.Value;
                
                %repeat for the tetrode stuff
                app.spkPropTet=sp(contains(sp,'All'));
                app.spkPropTet=app.spkPropTet(~strcmp(app.spkPropTet,'chListAll'));
                app.TetXaxisDropDown.Items=app.spkPropTet;
                app.TetYaxisDropDown.Items=app.spkPropTet;
                
                app.TetXaxisDropDown.Value=app.spkPropTet{1};
                app.TetYaxisDropDown.Value=app.spkPropTet{1};
                app.xPropTet=app.TetXaxisDropDown.Value;
                app.yPropTet=app.TetYaxisDropDown.Value;
                
                app.detChTet=1;
                app.TetDetChSpinner.Value=1;
                app.TetDetChSpinner.Limits=[1 app.probes.nChannels];
                
                %to get channels for tetrode mode, need to find chlist for
                %channel 1 (that's the intial selection)
                idx=find(app.spkInfo.detCh==1);
                chList=app.spkInfo.chListAll{idx(1)};
                chList=cellstr(num2str(chList'));
                
                app.TetChXDropDown.Items=chList;
                app.TetChXDropDown.Value=num2str(chList{1});
                app.xChTet=str2num(app.TetChXDropDown.Value);
                
                app.TetChYDropDown.Items=chList;
                app.TetChYDropDown.Value=num2str(chList{2});
                app.yChTet=str2num(app.TetChYDropDown.Value);
                
                %initialize unit stuff
                app.units=struct;
                app.spkInfo.unitid=zeros(size(app.spkInfo.detCh));
                app.nrUnits=0;
                app.unitInfo=table;
                
                
                %load sorting file (if it exists)
                sidx=exist(fullfile(p,replace(f,'id',['p' num2str(app.spkInfo.probeId) '_spkSort'])),'file');
                if sidx==2 %file exist
                    load(fullfile(p,replace(f,'id',['p' num2str(app.spkInfo.probeId) '_spkSort']))); %generates spkSort
                    %copy sort info into unit
                    app.spkInfo.unitid=spkSort.unitid;
                    
                    %set initials field
                    app.InitialsField.Value=spkSort.name;
                    
                    %set up nr units, unit tables, colors and isi
                    c=colororder;
                    c=c(2:end,:); %first one reserved for unassigned units
                    tnames={'UnitId','Vis','Cat','ISIv'};
                    
                    app.nrUnits=max(app.spkInfo.unitid);
                    
                    for i=1:app.nrUnits
                        val(i,:)={i,1,spkSort.unitinfo{i},NaN};
                        cidx=mod(j-1,size(c,1))+1;
                        app.units(i).color=c(cidx,:);
                    end
                    app.unitInfo=cell2table(val,'VariableNames',tnames);
                    
                    %this updates the entry in the table (needs table
                    %to exist, so separate loop)
                    for i=1:app.nrUnits
                        app.computeISI(i);
                    end
                    
                end
                
                if app.nrUnits>0
                    %update waveform plot
                    app.updateWfPlot(1);
                    
                    %update footprint plot
                    app.updateFpPlot(1);
                end
                
                %update table
                app.updateUnitTable();
                
                %plot the spikes
                app.updateMainPlot();
                
                %update buttons
                app.setButtons(1);
                
                %update buttons for units
                app.setButtonsUnits(app.nrUnits,1);
                if app.nrUnits>0
                    app.SaveButton.Enable=1;
                end
                
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.InitialsField.Value)
                uialert(app.UISortGui,'Set initials before saving','Error');
                return;
            end
            
            %save the sorted data necessary for further analysis
            spkSort.unitid=app.spkInfo.unitid;
            
            %need to remove duplicates and artefacts if these have been
            %excluded from spike sorting
            
            spkSort.probeid=app.spkInfo.probeId;
            spkSort.spktimes=app.spkInfo.spkTimesDet;
            spkSort.name=app.InitialsField.Value;
            spkSort.date=date;
            spkSort.unitinfo=app.unitInfo.Cat;
            spkSort.expname=app.spkInfo.expname;
            spkSort.artNCh=app.artNCh;
            spkSort.artThreshold=app.artThreshold;
            
            save(fullfile(app.filepath,replace(app.filename,'spk','spkSort')),'spkSort');
            
            uialert(app.UISortGui,'Sort file saved','Done','Icon','success');
            
        end

        % Value changed function: MarkerSzSpinner
        function MarkerSzSpinnerValueChanged(app, event)
            %update plot
            app.updateMainPlot();
        end

        % Button pushed function: RejArtButton
        function RejArtButtonPushed(app, event)
            settingsHandle=setArtefact(app,app.spkInfo,app.jobEdges,app.filepath);
            waitfor(settingsHandle);
            
            %find and label artefacts
            if ~isnan(app.artNCh)
                if isnan(app.artThreshold) %only using channel criterion
                    artIdx = find(app.spkInfo.NDuplicate>app.artNCh);
                else %channel and threshold
                    if app.artThreshold<0
                        artIdx = find(app.spkInfo.NDuplicate>app.artNCh & ...
                            app.spkInfo.AmpMinDet<=app.artThreshold);
                    else
                        artIdx = find(app.spkInfo.NDuplicate>app.artNCh & ...
                            app.spkInfo.AmpMaxDet>=app.artThreshold);
                    end
                end
                app.spkInfo.unitid(artIdx)=-1;
            end
            app.updateMainPlot();
        end

        % Selection change function: ModeTabGroup
        function ModeTabGroupSelectionChanged(app, event)
            selectedTab = app.ModeTabGroup.SelectedTab;
            if strcmp(selectedTab.Title,'Probe mode')
                app.updateMainPlot();
            else
                app.updateMainPlotTet();
            end
        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownChanged(app, event)
            app.xProp=app.XaxisDropDown.Value;
            app.updateMainPlot();
        end

        % Value changed function: YaxisDropDown
        function YaxisDropDownChanged(app, event)
            app.yProp=app.YaxisDropDown.Value;
            app.updateMainPlot();
        end

        % Value changed function: TetDetChSpinner
        function TetDetChSpinnerValueChanged(app, event)
            app.detChTet = app.TetDetChSpinner.Value;
            
            %update dropdowns
            idx=find(app.spkInfo.detCh==app.detChTet);
            chList=app.spkInfo.chListAll{idx(1)};
            chList=cellstr(num2str(chList'));
            
            app.TetChXDropDown.Items=chList;
            app.TetChXDropDown.Value=num2str(chList{1});
            app.xChTet=str2num(app.TetChXDropDown.Value);
            
            app.TetChYDropDown.Items=chList;
            app.TetChYDropDown.Value=num2str(chList{2});
            app.yChTet=str2num(app.TetChYDropDown.Value);
            
            app.updateMainPlotTet();
        end

        % Value changed function: TetXaxisDropDown
        function TetXaxisDropDownValueChanged(app, event)
            app.xPropTet = app.TetXaxisDropDown.Value;
            app.updateMainPlotTet();
        end

        % Value changed function: TetYaxisDropDown
        function TetYaxisDropDownValueChanged(app, event)
            app.yPropTet = app.TetYaxisDropDown.Value;
            app.updateMainPlotTet();
        end

        % Value changed function: TetChXDropDown
        function TetChXDropDownValueChanged(app, event)
            app.xChTet = str2num(app.TetChXDropDown.Value);
            app.updateMainPlotTet();
        end

        % Value changed function: TetChYDropDown
        function TetChYDropDownValueChanged(app, event)
            app.yChTet = str2num(app.TetChYDropDown.Value);
            app.updateMainPlotTet();
        end

        % Button pushed function: LimitChButton
        function LimitChButtonPushed(app, event)
            settingsHandle=selectCh(app,app.probes,app.selectedCh);
            waitfor(settingsHandle)
            
            if ~isempty(app.selectedCh)
                chStr=num2str(app.selectedCh','%d, ');
                app.SelChLabel.Text=['Selected ch: ' chStr(1:end-1)];
            end
            app.updateMainPlot();
        end

        % Button pushed function: ResetChButton
        function ResetChButtonPushed(app, event)
            app.selectedCh=[];
            app.SelChLabel.Text='';
            app.updateMainPlot();
        end

        % Button pushed function: AddUnitButton
        function AddUnitButtonPushed(app, event)
            %draw roi
            newRoi=drawpolygon(app.MainAxes);
            wait(newRoi);
            
            
            %save info for unit - this is a new unit, so need to add new
            %entries (and only single ROI present)
            app.nrUnits=app.nrUnits+1;
            
            %roi info
            %app.units(app.probeId,nrUnit).roi{1}=newRoi.Position;
            %app.units(app.probeId,nrUnit).roisign(1)=1;
            %app.units(app.probeId,nrUnit).viewX{1}=app.xProp;
            %app.units(app.probeId,nrUnit).viewY{1}=app.yProp;
            
            %color info
            c=colororder;
            c=c(2:end,:); %first one reserved for unassigned units
            cidx=mod(app.nrUnits-1,size(c,1))+1;
            app.units(app.nrUnits).color=c(cidx,:);
            
            %add to table info
            val={app.nrUnits,1,'None',NaN};
            if app.nrUnits==1
                tnames={'UnitId','Vis','Cat','ISIv'};
                app.unitInfo=cell2table(val,'VariableNames',tnames);
            else
                app.unitInfo=[app.unitInfo;val];
            end
            
            %assign units - needs roi to still be active
            plotMode = app.ModeTabGroup.SelectedTab;
            if strcmp(plotMode.Title,'Probe mode')
                app.assignUnit(app.nrUnits,newRoi,1);
                app.updateMainPlot();
            else
                app.assignUnitTet(app.nrUnits,newRoi,1);
                app.updateMainPlotTet();
            end
            
            %compute ISI
            app.computeISI(app.nrUnits); %this updates the entry in the table
            
            %update table
            app.updateUnitTable();
            
            %delete roi for now
            delete(newRoi);
            
            %update waveform plot
            app.updateWfPlot(app.nrUnits);
            
            %update footprint plot
            app.updateFpPlot(app.nrUnits);
            
            %enable/update buttons
            app.SaveButton.Enable=1;
            app.setButtonsUnits(app.nrUnits,app.nrUnits);
        end

        % Value changed function: UnitSpinner
        function UnitSpinnerValueChanged(app, event)
            nrUnit = app.UnitSpinner.Value;
            
            %update settings of other buttons
            app.VisibleCheckBox.Value=app.unitInfo.Vis(nrUnit);
            app.CatDropDown.Value=app.unitInfo.Cat(nrUnit);
        end

        % Button pushed function: DeleteUnitButton
        function DeleteUnitButtonPushed(app, event)
            %identify selected unit
            remUnit=app.UnitSpinner.Value;
            
            %update unitid in spkInfo
            app.removeUnit(remUnit);
            
            %update plot
            app.updateMainPlot();
            
            %remove unit from table
            app.unitInfo(remUnit,:)=[];
            
            %update the rest and show
            for i=remUnit:app.nrUnits-1
                app.unitInfo.UnitId(i)=i;
            end
            app.updateUnitTable();
            
            %change unit number
            app.nrUnits=app.nrUnits-1;
            
            %change waveform and footprint plot to keep things consistent
            if app.nrUnits>0
                app.updateWfPlot(1);
                app.updateFpPlot(1);
            else
                cla(app.WfAxes);
                cla(app.FpAxes);
            end
            
            %set unit spinner to 1 (need to pick something), disable things
            %as appopriate
            app.setButtonsUnits(app.nrUnits,1);
            if app.nrUnits==0
                app.SaveButton.Enable=0;
            end
            
        end

        % Button pushed function: AddPROIButton
        function AddPROIButtonPushed(app, event)
            %get unit
            addUnit=app.UnitSpinner.Value;
            
            %add a positive ROI (to add units to a region)
            newRoi=drawpolygon(app.MainAxes);
            wait(newRoi);
            
            %add ROI to unit info
            %roinr=length(app.units(app.probeId,addUnit).roi)+1;
            %app.units(app.probeId,addUnit).roi{roinr}=newRoi.Position;
            %app.units(app.probeId,addUnit).roisign(roinr)=1;
            %app.units(app.probeId,addUnit).viewX{roinr}=app.xProp;
            %app.units(app.probeId,addUnit).viewY{roinr}=app.yProp;
            
            %assign units
            app.assignUnit(addUnit,newRoi,1); %needs roi to still be active
            
            %compute ISI
            app.computeISI(addUnit); %this updates the entry in the table
            
            %update table
            app.updateUnitTable();
            
            %update plot
            app.updateMainPlot();
            
            %update waveform plot
            app.updateWfPlot(addUnit);
            app.UnitWfSpinner.Value=addUnit;
            
            %update footprint plot
            app.updateFpPlot(addUnit);
            app.UnitFpSpinner.Value=addUnit;
            
            %delete roi
            delete(newRoi);
            
        end

        % Button pushed function: AddMROIButton
        function AddMROIButtonPushed(app, event)
            %get unit
            addUnit=app.UnitSpinner.Value;
            
            %add a positive ROI (to add units to a region)
            newRoi=drawpolygon(app.MainAxes,'StripeColor','g');
            wait(newRoi);
            
            %add ROI to unit info
            %roinr=length(app.units(app.probeId,addUnit).roi)+1;
            %app.units(app.probeId,addUnit).roi{roinr}=newRoi.Position;
            %app.units(app.probeId,addUnit).roisign(roinr)=-1;
            %app.units(app.probeId,addUnit).viewX{roinr}=app.xProp;
            %app.units(app.probeId,addUnit).viewY{roinr}=app.yProp;
            
            %assign units
            app.assignUnit(addUnit,newRoi,-1); %needs roi to still be active
            
            %compute ISI
            app.computeISI(addUnit); %this updates the entry in the table
            
            %update table
            app.updateUnitTable();
            
            %update plot
            app.updateMainPlot();
            
            %update waveform plot
            app.updateWfPlot(addUnit);
            app.UnitWfSpinner.Value=addUnit;
            
            %update footprint plot
            app.updateFpPlot(addUnit);
            app.UnitFpSpinner.Value=addUnit;
            
            %delete roi
            delete(newRoi);
            
        end

        % Value changed function: CatDropDown
        function CatDropDownValueChanged(app, event)
            %enter category for cluster in table
            value = app.CatDropDown.Value;
            nrUnit=app.UnitSpinner.Value;
            app.unitInfo.Cat(nrUnit)={value};
            
            %update table
            app.updateUnitTable();
            
        end

        % Value changed function: VisibleCheckBox
        function VisibleCheckBoxValueChanged(app, event)
            value = app.VisibleCheckBox.Value;
            nrUnit=app.UnitSpinner.Value;
            app.unitInfo.Vis(nrUnit)=value;
            
            %update table
            app.updateUnitTable();
            
            %update plot
            app.updateMainPlot();
        end

        % Value changed function: UnitWfSpinner
        function UnitWfSpinnerValueChanged(app, event)
            %change unit for waveform plot
            nrUnit = app.UnitWfSpinner.Value;
            app.updateWfPlot(nrUnit);
        end

        % Value changed function: NrSpkSpinner
        function NrSpkSpinnerValueChanged(app, event)
            %change unit for waveform plot
            nrUnit = app.UnitWfSpinner.Value;
            app.updateWfPlot(nrUnit);
        end

        % Button pushed function: RefreshWfButton
        function RefreshWfButtonPushed(app, event)
            %update waveform plot
            nrUnit = app.UnitWfSpinner.Value;
            app.updateWfPlot(nrUnit);
        end

        % Value changed function: UnitFpSpinner
        function UnitFpSpinnerValueChanged(app, event)
            %update footprint plot
            nrUnit = app.UnitFpSpinner.Value;
            app.updateFpPlot(nrUnit);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UISortGui = uifigure('Visible', 'off');
            app.UISortGui.Name = 'MATLAB App';
            app.MainAxes = uiaxes(app.UISortGui);
            title(app.MainAxes, '')
            xlabel(app.MainAxes, 'X')
            ylabel(app.MainAxes, 'Y')
            app.FilePanel = uipanel(app.UISortGui);
            app.FilePanel.Title = 'File';
            app.LoadButton = uibutton(app.FilePanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Pick ID file & load';
            app.SaveButton = uibutton(app.FilePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save file';
            app.InitialsEditFieldLabel = uilabel(app.FilePanel);
            app.InitialsEditFieldLabel.Text = 'Initials';
            app.InitialsField = uieditfield(app.FilePanel, 'text');
            app.JobIDStartLabel = uilabel(app.FilePanel);
            app.JobIDStartLabel.Text = 'Job ID Start';
            app.JIDStartSpinner = uispinner(app.FilePanel);
            app.StopSpinnerLabel = uilabel(app.FilePanel);
            app.StopSpinnerLabel.Text = 'Stop';
            app.JIDStopSpinner = uispinner(app.FilePanel);
            app.JIDStopSpinner.Value = 199;
            app.ProbeIDSpinnerLabel = uilabel(app.FilePanel);
            app.ProbeIDSpinnerLabel.Text = 'Probe ID';
            app.ProbeIDSpinner = uispinner(app.FilePanel);
            app.ProbeIDSpinner.Value = 1;
            app.IDFileLabel = uilabel(app.FilePanel);
            app.IDFileLabel.Text = 'ID File';
            app.FilenameField = uieditfield(app.FilePanel, 'text');
            app.FilenameField.Editable = 'off';
            app.UnitsPanel = uipanel(app.UISortGui);
            app.UnitsPanel.Title = 'Units';
            app.AddUnitButton = uibutton(app.UnitsPanel, 'push');
            app.AddUnitButton.ButtonPushedFcn = createCallbackFcn(app, @AddUnitButtonPushed, true);
            app.AddUnitButton.Enable = 'off';
            app.AddUnitButton.Text = 'Add unit';
            app.UnitTable = uitable(app.UnitsPanel);
            app.UnitTable.ColumnName = {'Unit'; 'Vis'; 'Cat'; 'ISIv'};
            app.UnitTable.RowName = {};
            app.EditunitPanel = uipanel(app.UnitsPanel);
            app.EditunitPanel.Title = 'Edit unit';
            app.AddPROIButton = uibutton(app.EditunitPanel, 'push');
            app.AddPROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddPROIButtonPushed, true);
            app.AddPROIButton.Enable = 'off';
            app.AddPROIButton.Text = 'Add + ROI';
            app.AddMROIButton = uibutton(app.EditunitPanel, 'push');
            app.AddMROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddMROIButtonPushed, true);
            app.AddMROIButton.Enable = 'off';
            app.AddMROIButton.Text = 'Add - ROI';
            app.VisibleCheckBox = uicheckbox(app.EditunitPanel);
            app.VisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisibleCheckBoxValueChanged, true);
            app.VisibleCheckBox.Enable = 'off';
            app.VisibleCheckBox.Text = 'Visible';
            app.VisibleCheckBox.Value = true;
            app.UnitSpinnerLabel = uilabel(app.EditunitPanel);
            app.UnitSpinnerLabel.Text = 'Unit';
            app.UnitSpinner = uispinner(app.EditunitPanel);
            app.UnitSpinner.ValueChangedFcn = createCallbackFcn(app, @UnitSpinnerValueChanged, true);
            app.UnitSpinner.Enable = 'off';
            app.UnitSpinner.Value = 1;
            app.DeleteUnitButton = uibutton(app.EditunitPanel, 'push');
            app.DeleteUnitButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteUnitButtonPushed, true);
            app.DeleteUnitButton.Enable = 'off';
            app.DeleteUnitButton.Text = 'Delete unit';
            app.CategoryLabel = uilabel(app.EditunitPanel);
            app.CategoryLabel.Text = 'Category';
            app.CatDropDown = uidropdown(app.EditunitPanel);
            app.CatDropDown.Items = {'None', 'SU', 'MU', 'Noise'};
            app.CatDropDown.ValueChangedFcn = createCallbackFcn(app, @CatDropDownValueChanged, true);
            app.CatDropDown.Enable = 'off';
            app.CatDropDown.Value = 'None';
            app.PlotPanel = uipanel(app.UISortGui);
            app.PlotPanel.Title = 'Plot';
            app.MarkersizeSpinnerLabel = uilabel(app.PlotPanel);
            app.MarkersizeSpinnerLabel.Text = 'Marker size';
            app.MarkerSzSpinner = uispinner(app.PlotPanel);
            app.MarkerSzSpinner.Limits = [1 10];
            app.MarkerSzSpinner.ValueChangedFcn = createCallbackFcn(app, @MarkerSzSpinnerValueChanged, true);
            app.MarkerSzSpinner.Enable = 'off';
            app.MarkerSzSpinner.Value = 2;
            app.ModeTabGroup = uitabgroup(app.PlotPanel);
            app.ModeTabGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeTabGroupSelectionChanged, true);
            app.ProbemodeTab = uitab(app.ModeTabGroup);
            app.ProbemodeTab.Title = 'Probe mode';
            app.XaxisDropDownLabel = uilabel(app.ProbemodeTab);
            app.XaxisDropDownLabel.Text = 'X axis';
            app.XaxisDropDown = uidropdown(app.ProbemodeTab);
            app.XaxisDropDown.Items = {};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownChanged, true);
            app.XaxisDropDown.Enable = 'off';
            app.XaxisDropDown.Value = {};
            app.YaxisDropDownLabel = uilabel(app.ProbemodeTab);
            app.YaxisDropDownLabel.Text = 'Y axis';
            app.YaxisDropDown = uidropdown(app.ProbemodeTab);
            app.YaxisDropDown.Items = {};
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownChanged, true);
            app.YaxisDropDown.Enable = 'off';
            app.YaxisDropDown.Value = {};
            app.LimitChButton = uibutton(app.ProbemodeTab, 'push');
            app.LimitChButton.ButtonPushedFcn = createCallbackFcn(app, @LimitChButtonPushed, true);
            app.LimitChButton.Enable = 'off';
            app.LimitChButton.Text = 'Limit channels';
            app.ResetChButton = uibutton(app.ProbemodeTab, 'push');
            app.ResetChButton.ButtonPushedFcn = createCallbackFcn(app, @ResetChButtonPushed, true);
            app.ResetChButton.Enable = 'off';
            app.ResetChButton.Text = 'Reset channels';
            app.SelChLabel = uilabel(app.ProbemodeTab);
            app.SelChLabel.Text = '';
            app.TetrodemodeTab = uitab(app.ModeTabGroup);
            app.TetrodemodeTab.Title = 'Tetrode mode';
            app.DetchannelSpinnerLabel = uilabel(app.TetrodemodeTab);
            app.DetchannelSpinnerLabel.Text = 'Det channel';
            app.TetDetChSpinner = uispinner(app.TetrodemodeTab);
            app.TetDetChSpinner.Limits = [1 Inf];
            app.TetDetChSpinner.ValueChangedFcn = createCallbackFcn(app, @TetDetChSpinnerValueChanged, true);
            app.TetDetChSpinner.Enable = 'off';
            app.TetDetChSpinner.Value = 1;
            app.XaxisDropDown_2Label = uilabel(app.TetrodemodeTab);
            app.XaxisDropDown_2Label.Text = 'X axis';
            app.TetXaxisDropDown = uidropdown(app.TetrodemodeTab);
            app.TetXaxisDropDown.Items = {};
            app.TetXaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @TetXaxisDropDownValueChanged, true);
            app.TetXaxisDropDown.Enable = 'off';
            app.TetXaxisDropDown.Value = {};
            app.YaxisDropDown_2Label = uilabel(app.TetrodemodeTab);
            app.YaxisDropDown_2Label.Text = 'Y axis';
            app.TetYaxisDropDown = uidropdown(app.TetrodemodeTab);
            app.TetYaxisDropDown.Items = {};
            app.TetYaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @TetYaxisDropDownValueChanged, true);
            app.TetYaxisDropDown.Enable = 'off';
            app.TetYaxisDropDown.Value = {};
            app.ChXLabel = uilabel(app.TetrodemodeTab);
            app.ChXLabel.Text = 'Ch X';
            app.TetChXDropDown = uidropdown(app.TetrodemodeTab);
            app.TetChXDropDown.Items = {};
            app.TetChXDropDown.ValueChangedFcn = createCallbackFcn(app, @TetChXDropDownValueChanged, true);
            app.TetChXDropDown.Enable = 'off';
            app.TetChXDropDown.Value = {};
            app.ChYLabel = uilabel(app.TetrodemodeTab);
            app.ChYLabel.Text = 'Ch Y';
            app.TetChYDropDown = uidropdown(app.TetrodemodeTab);
            app.TetChYDropDown.Items = {};
            app.TetChYDropDown.ValueChangedFcn = createCallbackFcn(app, @TetChYDropDownValueChanged, true);
            app.TetChYDropDown.Enable = 'off';
            app.TetChYDropDown.Value = {};
            app.UnitwaveformsPanel = uipanel(app.UISortGui);
            app.UnitwaveformsPanel.Title = 'Unit waveforms';
            app.UnitSpinner_2Label = uilabel(app.UnitwaveformsPanel);
            app.UnitSpinner_2Label.Text = 'Unit';
            app.UnitWfSpinner = uispinner(app.UnitwaveformsPanel);
            app.UnitWfSpinner.Limits = [1 Inf];
            app.UnitWfSpinner.ValueChangedFcn = createCallbackFcn(app, @UnitWfSpinnerValueChanged, true);
            app.UnitWfSpinner.Enable = 'off';
            app.UnitWfSpinner.Value = 1;
            app.WfAxes = uiaxes(app.UnitwaveformsPanel);
            title(app.WfAxes, '')
            xlabel(app.WfAxes, 'Samples')
            ylabel(app.WfAxes, 'Amp')
            app.RefreshWfButton = uibutton(app.UnitwaveformsPanel, 'push');
            app.RefreshWfButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshWfButtonPushed, true);
            app.RefreshWfButton.Enable = 'off';
            app.RefreshWfButton.Text = 'Refresh';
            app.NrSpkSpinnerLabel = uilabel(app.UnitwaveformsPanel);
            app.NrSpkSpinnerLabel.Text = 'Nr Spk';
            app.NrSpkSpinner = uispinner(app.UnitwaveformsPanel);
            app.NrSpkSpinner.Limits = [1 Inf];
            app.NrSpkSpinner.ValueChangedFcn = createCallbackFcn(app, @NrSpkSpinnerValueChanged, true);
            app.NrSpkSpinner.Enable = 'off';
            app.NrSpkSpinner.Value = 25;
            app.UnitfootprintPanel = uipanel(app.UISortGui);
            app.UnitfootprintPanel.Title = 'Unit footprint';
            app.UnitSpinner_2Label_2 = uilabel(app.UnitfootprintPanel);
            app.UnitSpinner_2Label_2.Text = 'Unit';
            app.UnitFpSpinner = uispinner(app.UnitfootprintPanel);
            app.UnitFpSpinner.Limits = [1 Inf];
            app.UnitFpSpinner.ValueChangedFcn = createCallbackFcn(app, @UnitFpSpinnerValueChanged, true);
            app.UnitFpSpinner.Enable = 'off';
            app.UnitFpSpinner.Value = 1;
            app.FpAxes = uiaxes(app.UnitfootprintPanel);
            title(app.FpAxes, '')
            xlabel(app.FpAxes, 'X')
            ylabel(app.FpAxes, 'Y')
            app.PreprocessingPanel = uipanel(app.UISortGui);
            app.PreprocessingPanel.Title = 'Preprocessing';
            app.RejArtButton = uibutton(app.PreprocessingPanel, 'push');
            app.RejArtButton.ButtonPushedFcn = createCallbackFcn(app, @RejArtButtonPushed, true);
            app.RejArtButton.Enable = 'off';
            app.RejArtButton.Text = 'Reject artefacts';
            % Show the figure after all components are created
            app.UISortGui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sortGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UISortGui)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UISortGui)
        end
    end
end